classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Sizeafter                  matlab.ui.control.Label
        Sizebefore                 matlab.ui.control.Label
        CompressButton             matlab.ui.control.Button
        RunLengthEncodingCheckBox  matlab.ui.control.CheckBox
        LempelZivWelchCheckBox     matlab.ui.control.CheckBox
        HuffmanCodingCheckBox      matlab.ui.control.CheckBox
        CompressedImageLabel       matlab.ui.control.Label
        OriginalImageLabel         matlab.ui.control.Label
        SelectImageButton          matlab.ui.control.Button
        CompressedImage            matlab.ui.control.UIAxes
        OriginalImage              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImageData; %orijinal görselin matris bilgilerini kaydeder.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
         [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif;*.jpeg', 'Image Files'}, 'Bir Görsel Seçin');

        if isequal(file, 0)
            return;
        end

        %görseli oku ve originalImage sekmesinde göster
        fullFileName = fullfile(path, file);
        fileInfo = imfinfo(fullFileName);
        fileSizeKB = fileInfo.FileSize / 1024; % KB'a çevir
        app.OriginalImageData = imread(fullFileName);
        app.Sizebefore.Text = sprintf('Boyut: %.2f KB', fileSizeKB);
        imshow(app.OriginalImageData, 'Parent', app.OriginalImage);

        %sağdaki alanı her seferinde temizle
        cla(app.CompressedImage);
        app.Sizeafter.Text = 'Boyut: -';
        end

        % Value changed function: HuffmanCodingCheckBox
        function HuffmanCodingCheckBoxValueChanged(app, event)
            value = app.HuffmanCodingCheckBox.Value; %checkbox durumunu value değişkenine atadım
            if value == true
                %Huffman metodu seçildi diğer checkboxları kapat
                app.LempelZivWelchCheckBox.Enable = 'off';
                app.RunLengthEncodingCheckBox.Enable = 'off';
            else
                %Seçim kaldırıldı diğer seçenkleri tekrar aktif yap
                app.LempelZivWelchCheckBox.Enable = 'on';
                app.RunLengthEncodingCheckBox.Enable = 'on';
            end
        end

        % Value changed function: LempelZivWelchCheckBox
        function LempelZivWelchCheckBoxValueChanged(app, event)
            value = app.LempelZivWelchCheckBox.Value;
            if value == true
                %LZW Metodu seçildi diğer checkboxları tıklanamaz yap
                app.HuffmanCodingCheckBox.Enable = 'off';
                app.RunLengthEncodingCheckBox.Enable = 'off';
            else
                app.HuffmanCodingCheckBox.Enable = 'on';
                app.RunLengthEncodingCheckBox.Enable = 'on';
            end
        end

        % Value changed function: RunLengthEncodingCheckBox
        function RunLengthEncodingCheckBoxValueChanged(app, event)
            value = app.RunLengthEncodingCheckBox.Value;
            if value == true
                %RLE Metodu seçildi diğer checkboxları tıklanamaz yap
                app.HuffmanCodingCheckBox.Enable = 'off';
                app.LempelZivWelchCheckBox.Enable = 'off';
            else
                app.HuffmanCodingCheckBox.Enable = 'on';
                app.LempelZivWelchCheckBox.Enable = 'on';
            end
        end

        % Button pushed function: CompressButton
        function CompressButtonPushed(app, event)
            % 1. Önce görsel yüklenmiş mi kontrol et
            if isempty(app.OriginalImageData)
                uialert(app.UIFigure, 'Lütfen önce bir görsel seçin!', 'Hata');
                return;
            end
        
            % Değişkenleri hazırla
            methodName = '';
            compSizeKB = 0; % Hesaplanan boyut burada tutulacak
            
            % Hata yönetimi (Try-Catch) ile güvenli çalışma
            try
                % 2. Seçilen yönteme göre fonksiyonu çağır ve BOYUTU AL
                % DİKKAT: [~, compSizeKB] diyerek ikinci çıktıyı alıyoruz.
                
                if app.HuffmanCodingCheckBox.Value
                    methodName = 'Huffman Coding';
                    [~, compSizeKB] = huffman_compress(app.OriginalImageData);
                    
                elseif app.LempelZivWelchCheckBox.Value
                    methodName = 'Lempel-Ziv-Welch (LZW)';
                    [~, compSizeKB] = lzw_compress(app.OriginalImageData);
                    
                elseif app.RunLengthEncodingCheckBox.Value
                    methodName = 'Run Length Encoding (RLE)';
                    [~, compSizeKB] = rle_compress(app.OriginalImageData);
                    
                else
                    uialert(app.UIFigure, 'Lütfen bir sıkıştırma yöntemi seçin.', 'Uyarı');
                    return;
                end
            
                % 3. ARAYÜZÜ GÜNCELLE (FIX)
                % Sağ taraftaki "Boyut: -" yazan etiketi değiştiriyoruz.
                app.Sizeafter.Text = sprintf('Boyut: %.2f KB', compSizeKB);
            
                % Görüntüyü sağ tarafa bas (Kayıpsız olduğu için görsel değişmez)
                imshow(app.OriginalImageData, 'Parent', app.CompressedImage);
                title(app.CompressedImage, ['Sonuç: ' methodName]);
            
                % 4. SONUÇ UYARISI VER (FIX)
                % Artık sabit mesaj yerine hesaplanan boyutu yazdırıyoruz.
                resultMsg = sprintf('İşlem Başarılı!\n\nUygulanan Yöntem: %s\nHesaplanan Yeni Boyut: %.2f KB', methodName, compSizeKB);
                uialert(app.UIFigure, resultMsg, 'Sıkıştırma Tamamlandı');
                
            catch ME
                % Eğer dış fonksiyonlarda hata varsa burada yakala
                uialert(app.UIFigure, ['Hata oluştu: ' ME.message], 'Sistem Hatası');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImage = uiaxes(app.UIFigure);
            app.OriginalImage.XTick = [];
            app.OriginalImage.XTickLabel = '';
            app.OriginalImage.YTick = [];
            app.OriginalImage.ZTick = [];
            app.CompressedImage = uiaxes(app.UIFigure);
            app.CompressedImage.XTick = [];
            app.CompressedImage.XTickLabel = '';
            app.CompressedImage.YTick = [];
            app.CompressedImage.ZTick = [];
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.CompressedImageLabel = uilabel(app.UIFigure);
            app.CompressedImageLabel.Text = 'Compressed Image';
            app.HuffmanCodingCheckBox = uicheckbox(app.UIFigure);
            app.HuffmanCodingCheckBox.ValueChangedFcn = createCallbackFcn(app, @HuffmanCodingCheckBoxValueChanged, true);
            app.HuffmanCodingCheckBox.Text = 'Huffman Coding';
            app.LempelZivWelchCheckBox = uicheckbox(app.UIFigure);
            app.LempelZivWelchCheckBox.ValueChangedFcn = createCallbackFcn(app, @LempelZivWelchCheckBoxValueChanged, true);
            app.LempelZivWelchCheckBox.Text = 'Lempel-Ziv-Welch';
            app.RunLengthEncodingCheckBox = uicheckbox(app.UIFigure);
            app.RunLengthEncodingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunLengthEncodingCheckBoxValueChanged, true);
            app.RunLengthEncodingCheckBox.Text = 'Run Length Encoding';
            app.CompressButton = uibutton(app.UIFigure, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressButtonPushed, true);
            app.CompressButton.Text = 'Compress';
            app.Sizebefore = uilabel(app.UIFigure);
            app.Sizebefore.Text = 'Boyut: -';
            app.Sizeafter = uilabel(app.UIFigure);
            app.Sizeafter.Text = 'Boyut: -';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end